classdef interpolation_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        SavetoFileSwitch               matlab.ui.control.Switch
        SavetoFileSwitchLabel          matlab.ui.control.Label
        InterpolationFileButton        matlab.ui.control.Button
        InterpolateFromFileEditField   matlab.ui.control.EditField
        InterpolateFromFileEditFieldLabel  matlab.ui.control.Label
        WheelAngleSpinner              matlab.ui.control.Spinner
        WheelAngleSpinnerLabel         matlab.ui.control.Label
        WheelAngleDegreesSlider        matlab.ui.control.Slider
        WheelAngleLabel                matlab.ui.control.Label
        SpeedSpinner                   matlab.ui.control.Spinner
        SpeedSpinnerLabel              matlab.ui.control.Label
        SpeedcmsSlider                 matlab.ui.control.Slider
        SpeedcmsSliderLabel            matlab.ui.control.Label
        DepthSpinner                   matlab.ui.control.Spinner
        DepthSpinnerLabel              matlab.ui.control.Label
        DepthcmSlider                  matlab.ui.control.Slider
        DepthcmSliderLabel             matlab.ui.control.Label
        HeadingSpinner                 matlab.ui.control.Spinner
        HeadingSpinnerLabel            matlab.ui.control.Label
        HeadingDegreesSlider           matlab.ui.control.Slider
        HeadingDegreesSliderLabel      matlab.ui.control.Label
        InterpolateButton              matlab.ui.control.Button
        ChooseButton                   matlab.ui.control.Button
        ARCFileLocationEditField       matlab.ui.control.EditField
        ARCFileLocationEditFieldLabel  matlab.ui.control.Label
        ForcestoInterpolateLabel       matlab.ui.control.Label
        Tree                           matlab.ui.container.CheckBoxTree
        BodyForcesNode                 matlab.ui.container.TreeNode
        FxNode                         matlab.ui.container.TreeNode
        FyNode                         matlab.ui.container.TreeNode
        FzNode                         matlab.ui.container.TreeNode
        MxNode                         matlab.ui.container.TreeNode
        MyNode                         matlab.ui.container.TreeNode
        MzNode                         matlab.ui.container.TreeNode
        WheelForcesNode                matlab.ui.container.TreeNode
        WheelFxNode                    matlab.ui.container.TreeNode
        WheelFyNode                    matlab.ui.container.TreeNode
        WheelFzNode                    matlab.ui.container.TreeNode
        WheelMxNode                    matlab.ui.container.TreeNode
        WheelMyNode                    matlab.ui.container.TreeNode
        WheelMzNode                    matlab.ui.container.TreeNode
        RightPanel                     matlab.ui.container.Panel
        InterpolationtookLabel         matlab.ui.control.Label
        Timefield                      matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        ARCtable % interpolation table of arc data
        isTableLoaded = false; % has ARCtable been loaded?
        Forces2interp % cell array to extract the required fields from ARCtable
        Heading2Interp = 0; % double of the heading the user wishes to interpolate. Index 1 of ARCtable.Force.Steering.P
        Depth2Interp = 0.1; % double of the depth the user wishes to interpolate. Index 2 of ARCtable.Force.Steering.P
        Speed2Interp = 0.5 % double of the speed the user wishes to interpolate. Index 3 of ARCtable.Force.Steering.P
        SteeringSelect = "Center"; % 
        Steering2Interp = 0; % double array of the steering field the user wishes to interp. If no selection is made default to center
        File2Interp % a file that the user wishes to interpolate
        File2InterpSteering % steering values from the file2interp that are passed to getSteering to populate SteeringSelect and Steering2Interp correctly
        Save2File % toggles whether a file will be written.
        SaveFileName = "ARC_Interpolated_File.txt";
    end
    
    methods (Access = private)
        
        function getSteering(app,steeringValue)
            if steeringValue > 0 
                if steeringValue == 15
                    app.SteeringSelect = "Port";
                else
                    app.SteeringSelect = ["Starboard","Center","Port"];
                    app.Steering2Interp = [-15,0,15,steeringValue];
                end
            elseif steeringValue < 0
                if steeringValue == -15
                    app.SteeringSelect = "Starboard";
                else
                    app.SteeringSelect = ["Starboard","Center","Port"];
                    app.Steering2Interp = [-15,0,15,steeringValue];
                end
            else
                app.SteeringSelect = "Center";
            end
        end
        
        function SetFileInterpolation(app)
            app.File2InterpSteering = app.File2Interp(:,1);
            app.Heading2Interp = app.File2Interp(:,2);
            app.Depth2Interp = app.File2Interp(:,3);
            app.Speed2Interp = app.File2Interp(:,4);  
        end
        
        function v = Interpolate(app,k)
            % k is the index of each item to interpolate. e.g.
            % app.Heading2Interp(1). Returns the vector it made
            v = zeros(1,length(app.Forces2interp));
            for i = 1:length(app.Forces2interp)
                if contains(app.Forces2interp{i},"Wheel")
                    % correction between table value and app value
                    field = strrep(app.Forces2interp{i},' ','');
                else
                    field = app.Forces2interp{i};
                end
                P = cell(1,length(app.SteeringSelect));
                values = cell(1,length(app.SteeringSelect));
                tempV = zeros(1,length(app.SteeringSelect));
                for j = 1:length(app.SteeringSelect)
                    P{j} = app.ARCtable.(field).(app.SteeringSelect{j}).P;
                    values{j} = app.ARCtable.(field).(app.SteeringSelect{j}).Values;
                    % Normalize to get a smoother surface, F
                    [P{j},C,S] = normalize(P{j});             
                    F = scatteredInterpolant(P{j},values{j});
                    F.Method = 'linear'; % 'natural'
                    normHeading2Interp = (app.Heading2Interp(k)-C(1))/S(1);
                    normDepth2Interp = (app.Depth2Interp(k)-C(2))/S(2);
                    normSpeed2Interp = (app.Speed2Interp(k)-C(3))/S(3);
                    %plotDelaunayTriangulation(app,P{j},normHeading2Interp,normDepth2Interp,normSpeed2Interp);
                    % Heading, depth, speed (to match P)
                    tempV(j) = F(normHeading2Interp,normDepth2Interp,normSpeed2Interp);
                end
                if length(app.SteeringSelect)>1
                    A = app.Steering2Interp(1:3);
                    v(i) = interp1(A,tempV,app.Steering2Interp(end),'makima'); % linear or (makima or cubic)   
                else
                    v(i) = tempV(1);
                end
            end
        end
        
        function X = checkFileLimits(app, A)
            % function checks each column of A for values that exceed the 
            % limits imposed by the different GUI inputs if a value is over
            % the limit it is capped to the limit and a warning is 
            % displayed to the user
            allowedValues = [app.WheelAngleSpinner.Limits;app.HeadingSpinner.Limits;...
                             app.DepthSpinner.Limits./100;app.SpeedSpinner.Limits./100];
            X = zeros(size(A));
            for i = 1:size(A,2)
                % Find elements in the column that fall outside of the
                % allowed range
                X(:,i) = A(:,i); 
                outofRangeLow = A(:,i) < allowedValues(i,1);
                outofRangeHigh = A(:,i) > allowedValues(i,2);
                % Replace out-of-range elements with the nearest allowed
                % value
                if any(outofRangeLow)
                    fig = uifigure;
                    message = {'Element out of range!'};
                    uialert(fig,message,"Warning","Icon","warning");
                    X(outofRangeLow,i) = allowedValues(i,1);
                    fprintf('Element at [%d,%d] out of range, replaced with %4.3f\n',outofRangeLow,i,allowedValues(i,1));
                elseif any(outofRangeHigh)
                    fig = uifigure;
                    message = {'Element out of range!'};
                    uialert(fig,message,"Warning","Icon","warning");
                    X(outofRangeHigh,i) = allowedValues(i,2);
                    fprintf('Element at [%d,%d] out of range, replaced with %4.3f\n',outofRangeHigh,i,allowedValues(i,2));
                end
            end
        end
        
        function plotDelaunayTriangulation(app,P,X,Y,Z)
            % plots the Delaunay Triangulation for the points in P. X,Y,Z
            % are the points which we wish to interpolate at
            DT = delaunayTriangulation(P);
            tetramesh(DT,'FaceAlpha',0.3);
            hold on
            plot3(P(:,1),P(:,2),P(:,3),'mo');
            plot3(X,Y,Z,'r*');
            hold off
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fig = uifigure;
                selection = uiconfirm(fig,"Load the ARC File",...
                    "Load ARC File","Icon","info");
                switch selection
                    case 'OK'
                        delete(fig);
                        [ARCfile,ARCpath] = uigetfile("*.mat",...
                        'Select the ARC Interpolation Table',"ARC_Interpolation_Table.mat");
                        app.ARCFileLocationEditField.Value = ARCpath;
                        load (ARCfile)
                        app.ARCtable = interpHydro;
                        app.isTableLoaded = true;
                    case 'Cancel'
                        delete(fig);
                        app.delete;
                end 
            
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            count = 1;
            if ~isempty(app.Forces2interp)
                app.Forces2interp = []; %clear contents on next interpolation
            end
            for i = 1:length(checkedNodes)
                temp = checkedNodes(i).Text;
                if strcmp(temp,'Body Forces') || strcmp(temp,'Wheel Forces')
                    % we want to still allow the user to select all, but we
                    % will handle it the same as the others at the cost of
                    % maybe a little speed and efficiency
                    continue
                else
                    app.Forces2interp{count} = temp;
                    count = count + 1;
                end
            end
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            [ARCfile,ARCpath] = uigetfile("*.mat",...
                'Select the ARC Interpolation Table',"ARC_Interpolation_Table.mat");
            app.ARCFileLocationEditField.Value = ARCpath;
            load (ARCfile)
            app.ARCtable = interpHydro;
            app.isTableLoaded = true;

        end

        % Button pushed function: InterpolateButton
        function InterpolateButtonPushed(app, event)
            if app.isTableLoaded
                try
                    tic
                    if isempty(app.Forces2interp)
                        % default behavior is to interp all if no selection
                        % is made
                        app.Forces2interp = fieldnames(app.ARCtable)';
                    end
                    if ~isempty(app.UITable.Data)
                        app.UITable.Data = []; %clear the contents
                    end
                    if isempty(app.File2Interp)
                        v = Interpolate(app,1);
                        %must write to table all at once
                        Component = app.Forces2interp';
                        t = table(Component,v'); 
                        app.UITable.Data = t;
                    else
                        nFiles = height(app.File2Interp);
                        nForces = length(app.Forces2interp); % will almost always be 6
                        v = zeros(nFiles,nForces);
                        for i=1:nFiles
                            % get the current steering select and value to
                            % interpolate
                            getSteering(app,app.File2InterpSteering(i));
                            % pass the index to interpolate to the function
                            v(i,:) = Interpolate(app,i);
                        end
                        %must write to table all at once
                        Component = app.Forces2interp';
                        t = table(Component,v(end,:)'); % write the last only
                        app.UITable.Data = t;
                        app.File2Interp = []; % reset value
                    end
                    % should make this its own callback on the switch so
                    % that whatever is in the table can also be saved
                    if app.Save2File
                        save(app.SaveFileName,"v","-ascii");
                    end
                    app.Timefield.Value = toc;
                catch
                    fig = uifigure;
                    selection = uiconfirm(fig,"Something went wrong :(","Error","Icon","error");
                    switch selection
                        case 'OK'
                            delete(fig);
                        case 'Cancel'
                            delete(fig);
                            return
                    end
                end
            else
                fig = uifigure;
                selection = uiconfirm(fig,"ARC file not found!",...
                    "Invalid File","Icon","error");
                switch selection
                    case 'OK'
                        delete(fig);
                        pause(1); % wait a second before opening the window
                        ChooseButtonPushed(app,event);
                    case 'Cancel'
                        return % exit the sequence
                end 
            end
        end

        % Value changing function: HeadingDegreesSlider
        function HeadingDegreesSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Heading2Interp = changingValue;
            app.HeadingSpinner.Value = changingValue;
        end

        % Value changing function: HeadingSpinner
        function HeadingSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.HeadingDegreesSlider.Value = changingValue;
            app.Heading2Interp = changingValue;
        end

        % Value changing function: DepthcmSlider
        function DepthcmSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Depth2Interp = changingValue/100; %convert cm to m
            app.DepthSpinner.Value = changingValue;
        end

        % Value changing function: DepthSpinner
        function DepthSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.DepthcmSlider.Value = changingValue;
            app.Depth2Interp = changingValue/100; %convert cm to m
        end

        % Value changing function: SpeedcmsSlider
        function SpeedcmsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Speed2Interp = changingValue/100; %convert cm/s to m/s
            app.SpeedSpinner.Value = changingValue;
        end

        % Value changing function: SpeedSpinner
        function SpeedSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.Speed2Interp = changingValue/100; %convert cm/s to m/s
            app.SpeedcmsSlider.Value = changingValue;
        end

        % Value changing function: WheelAngleDegreesSlider
        function WheelAngleDegreesSliderValueChanging(app, event)
            changingValue = event.Value;
            app.WheelAngleSpinner.Value = changingValue;
            getSteering(app,changingValue);

        end

        % Value changing function: WheelAngleSpinner
        function WheelAngleSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.WheelAngleDegreesSlider.Value = changingValue;
            getSteering(app,changingValue);
        end

        % Button pushed function: InterpolationFileButton
        function InterpolationFileButtonPushed(app, event)
            [interpolationfile,interpolationpath] = uigetfile("*.txt",...
                'Select an interpolation file');
            app.InterpolateFromFileEditField.Value = interpolationpath;
            formatSpec = '%f';
            fileID = fopen(interpolationfile,'r');
            temp = fscanf(fileID,formatSpec);
            % should add a check here for numeric data and throw an error
            % if data is not numeric!
            count = 1;
            first = 1;
            A = zeros(length(temp)/4,4);
            % turn temp column vector into A row vector. Each row is a new
            % interpolation query
            for i=1:length(temp)
                % check inputs of temp first
                if mod(i,4) == 0
                    A(count,:) = temp(first:i);
                    first = i+1;
                    count = count+1;
                end
            end
            A = checkFileLimits(app,A);
            app.File2Interp = A;
            SetFileInterpolation(app);
        end

        % Value changed function: SavetoFileSwitch
        function SavetoFileSwitchValueChanged(app, event)
            value = app.SavetoFileSwitch.Value;
            if strcmp(value,'Yes')
                app.Save2File = true;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {623, 623};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {578, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Tree = uitree(app.LeftPanel, 'checkbox');
            app.BodyForcesNode = uitreenode(app.Tree);
            app.BodyForcesNode.Text = 'Body Forces';
            app.FxNode = uitreenode(app.BodyForcesNode);
            app.FxNode.Text = 'Fx';
            app.FyNode = uitreenode(app.BodyForcesNode);
            app.FyNode.Text = 'Fy';
            app.FzNode = uitreenode(app.BodyForcesNode);
            app.FzNode.Text = 'Fz';
            app.MxNode = uitreenode(app.BodyForcesNode);
            app.MxNode.Text = 'Mx';
            app.MyNode = uitreenode(app.BodyForcesNode);
            app.MyNode.Text = 'My';
            app.MzNode = uitreenode(app.BodyForcesNode);
            app.MzNode.Text = 'Mz';
            app.WheelForcesNode = uitreenode(app.Tree);
            app.WheelForcesNode.Text = 'Wheel Forces';
            app.WheelFxNode = uitreenode(app.WheelForcesNode);
            app.WheelFxNode.Text = 'Wheel Fx';
            app.WheelFyNode = uitreenode(app.WheelForcesNode);
            app.WheelFyNode.Text = 'Wheel Fy';
            app.WheelFzNode = uitreenode(app.WheelForcesNode);
            app.WheelFzNode.Text = 'Wheel Fz';
            app.WheelMxNode = uitreenode(app.WheelForcesNode);
            app.WheelMxNode.Text = 'Wheel Mx';
            app.WheelMyNode = uitreenode(app.WheelForcesNode);
            app.WheelMyNode.Text = 'Wheel My';
            app.WheelMzNode = uitreenode(app.WheelForcesNode);
            app.WheelMzNode.Text = 'Wheel Mz';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.ForcestoInterpolateLabel = uilabel(app.LeftPanel);
            app.ForcestoInterpolateLabel.Text = 'Forces to Interpolate';
            app.ARCFileLocationEditFieldLabel = uilabel(app.LeftPanel);
            app.ARCFileLocationEditFieldLabel.Text = {'ARC File'; 'Location'};
            app.ARCFileLocationEditField = uieditfield(app.LeftPanel, 'text');
            app.ChooseButton = uibutton(app.LeftPanel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.InterpolateButton = uibutton(app.LeftPanel, 'push');
            app.InterpolateButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButton.Text = 'Interpolate';
            app.HeadingDegreesSliderLabel = uilabel(app.LeftPanel);
            app.HeadingDegreesSliderLabel.Text = {'Heading'; '(Degrees)'};
            app.HeadingDegreesSlider = uislider(app.LeftPanel);
            app.HeadingDegreesSlider.Limits = [0 180];
            app.HeadingDegreesSlider.MajorTicks = [0 45 90 135 180];
            app.HeadingDegreesSlider.ValueChangingFcn = createCallbackFcn(app, @HeadingDegreesSliderValueChanging, true);
            app.HeadingDegreesSlider.MinorTicks = [0 22.5 45 67.5 90 112.5 135 157.5 180];
            app.HeadingSpinnerLabel = uilabel(app.LeftPanel);
            app.HeadingSpinnerLabel.Text = 'Heading';
            app.HeadingSpinner = uispinner(app.LeftPanel);
            app.HeadingSpinner.ValueChangingFcn = createCallbackFcn(app, @HeadingSpinnerValueChanging, true);
            app.HeadingSpinner.Limits = [0 180];
            app.HeadingSpinner.ValueDisplayFormat = '%4.1f deg';
            app.DepthcmSliderLabel = uilabel(app.LeftPanel);
            app.DepthcmSliderLabel.Text = {'Depth'; '(cm)'};
            app.DepthcmSlider = uislider(app.LeftPanel);
            app.DepthcmSlider.Limits = [4.88 20.95];
            app.DepthcmSlider.MajorTicks = [5 8 12 16 20];
            app.DepthcmSlider.ValueChangingFcn = createCallbackFcn(app, @DepthcmSliderValueChanging, true);
            app.DepthcmSlider.MinorTicks = [4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22];
            app.DepthcmSlider.Value = 5;
            app.DepthSpinnerLabel = uilabel(app.LeftPanel);
            app.DepthSpinnerLabel.Text = 'Depth';
            app.DepthSpinner = uispinner(app.LeftPanel);
            app.DepthSpinner.ValueChangingFcn = createCallbackFcn(app, @DepthSpinnerValueChanging, true);
            app.DepthSpinner.Limits = [4.88 20.95];
            app.DepthSpinner.ValueDisplayFormat = '%4.3f cm';
            app.DepthSpinner.Value = 4.88;
            app.SpeedcmsSliderLabel = uilabel(app.LeftPanel);
            app.SpeedcmsSliderLabel.Text = {'Speed'; '(cm/s)'};
            app.SpeedcmsSlider = uislider(app.LeftPanel);
            app.SpeedcmsSlider.Limits = [20.45 89];
            app.SpeedcmsSlider.MajorTicks = [21 30 40 50 60 70 80 89];
            app.SpeedcmsSlider.ValueChangingFcn = createCallbackFcn(app, @SpeedcmsSliderValueChanging, true);
            app.SpeedcmsSlider.MinorTicks = [20 25 30 35 40 45 50 55 60 65 70 75 80 85 90];
            app.SpeedcmsSlider.Value = 20.45;
            app.SpeedSpinnerLabel = uilabel(app.LeftPanel);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.LeftPanel);
            app.SpeedSpinner.ValueChangingFcn = createCallbackFcn(app, @SpeedSpinnerValueChanging, true);
            app.SpeedSpinner.Limits = [20.45 89];
            app.SpeedSpinner.ValueDisplayFormat = '%4.3f cm/s';
            app.SpeedSpinner.Value = 20.45;
            app.WheelAngleLabel = uilabel(app.LeftPanel);
            app.WheelAngleLabel.Text = {'Wheel'; 'Angle'; '(Degrees)'};
            app.WheelAngleDegreesSlider = uislider(app.LeftPanel);
            app.WheelAngleDegreesSlider.Limits = [-15 15];
            app.WheelAngleDegreesSlider.ValueChangingFcn = createCallbackFcn(app, @WheelAngleDegreesSliderValueChanging, true);
            app.WheelAngleSpinnerLabel = uilabel(app.LeftPanel);
            app.WheelAngleSpinnerLabel.Text = {'Wheel'; 'Angle'};
            app.WheelAngleSpinner = uispinner(app.LeftPanel);
            app.WheelAngleSpinner.ValueChangingFcn = createCallbackFcn(app, @WheelAngleSpinnerValueChanging, true);
            app.WheelAngleSpinner.Limits = [-15 15];
            app.WheelAngleSpinner.ValueDisplayFormat = '%3.1f deg';
            app.InterpolateFromFileEditFieldLabel = uilabel(app.LeftPanel);
            app.InterpolateFromFileEditFieldLabel.Text = {'Interpolate'; ' From File'};
            app.InterpolateFromFileEditField = uieditfield(app.LeftPanel, 'text');
            app.InterpolationFileButton = uibutton(app.LeftPanel, 'push');
            app.InterpolationFileButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolationFileButtonPushed, true);
            app.InterpolationFileButton.Text = 'Interpolation File';
            app.SavetoFileSwitchLabel = uilabel(app.LeftPanel);
            app.SavetoFileSwitchLabel.Text = 'Save to File';
            app.SavetoFileSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SavetoFileSwitch.Items = {'No', 'Yes'};
            app.SavetoFileSwitch.ValueChangedFcn = createCallbackFcn(app, @SavetoFileSwitchValueChanged, true);
            app.SavetoFileSwitch.Value = 'No';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Component'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.Timefield = uieditfield(app.RightPanel, 'numeric');
            app.Timefield.ValueDisplayFormat = '%5.4f seconds';
            app.Timefield.Editable = 'off';
            app.InterpolationtookLabel = uilabel(app.RightPanel);
            app.InterpolationtookLabel.Text = 'Interpolation took:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interpolation_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end